classdef basemap_manager < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        GridLayout                matlab.ui.container.GridLayout
        FilePrefixEditField       matlab.ui.control.EditField
        FilePrefixEditFieldLabel  matlab.ui.control.Label
        DoneButton                matlab.ui.control.Button
        AddMapsButton             matlab.ui.control.Button
        FilesFoundLabel           matlab.ui.control.Label
        FindFilesButton           matlab.ui.control.Button
        FilesPathTextArea         matlab.ui.control.TextArea
        FilesPathTextAreaLabel    matlab.ui.control.Label
        FilesFoundListBox         matlab.ui.control.ListBox
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.Visible="off";            
            movegui(app.UIFigure,'center');
            app.UIFigure.WindowStyle="normal";
            app.UIFigure.Visible="on"; 
        end

        % Button pushed function: FindFilesButton
        function FindFilesButtonPushed(app, event)
            direc=uigetdir;
            files_found={};
            zoom_levels=[];
            file_prefix=app.FilePrefixEditField.Value;
            valid_files_index=1;
            for i=1:20
                file_path=[direc '\' file_prefix '_' num2str(i) '.mbtiles'];
                if isfile(file_path)
                    files_found{valid_files_index}=file_path;
                    zoom_levels(valid_files_index)=i;
                    valid_files_index=valid_files_index+1;
                end
            end
            if ~isempty(files_found)
                app.AddMapsButton.Enable="on";
                app.AddMapsButton.Visible="on";
            end
            app.FilesFoundListBox.Items=files_found;
            app.FilesFoundListBox.ItemsData=zoom_levels;
            app.UIFigure.focus();
            app.FilesFoundLabel.Text=sprintf("Files Found: %d",length(files_found));
            app.FilesPathTextArea.Value=direc;
            app.AddMapsButton.Visible="on";
        end

        % Button pushed function: AddMapsButton
        function AddMapsButtonPushed(app, event)
            files_found=app.FilesFoundListBox.Items;
            max_available=length(files_found);
            zoom_levels=app.FilesFoundListBox.ItemsData;
            map_prefix=app.FilePrefixEditField.Value;
            map_info.prefix=map_prefix;
            map_info.zoom_level_status=[];
            map_info.files_path=app.FilesPathTextArea.Value{1};
            maps_added={};
            maps_added_index=1;
            valid_files_index=1;
            % for now, assuming fixed zoom levels from 0 to 20. Files may
            % or may not exist for some levels, for those, zoom_status=0
            for level=0:20
                file_path=files_found{valid_files_index};
                zoom=zoom_levels(valid_files_index);
                map_name=[map_prefix '_' num2str(level)];
                if(zoom==level)
                    try
                        addCustomBasemap(map_name,file_path,"Attribution",'Developed by Sensors Div, NASTP','IsDeployable',true);
                        fprintf('map_name=%s, Zoom=%d, file=%s\n',map_name,zoom,file_path);
                        map_info.zoom_level_status(level+1)=1; % this zoom level exists
                        maps_added{maps_added_index}=map_name;
                        maps_added_index=maps_added_index+1;
                        valid_files_index=valid_files_index+1;
                        % can't go beyond the available files
                        if(valid_files_index>max_available)
                            break;
                        end
                    catch ex
                        map_info.zoom_level_status(level+1)=0; % this zoom level does not exist
                        fprintf('error adding map %s : %s',map_name,ex.message);
                    end
                else
                    map_info.zoom_level_status(level+1)=0; % this zoom level does not exist
                end
            end
            app.FilesFoundListBox.Items=maps_added;
            app.FilesFoundListBox.Visible="on";
            info_file_path=[pwd '\base_map_info'];
            save(info_file_path,"map_info");
            app.FilesFoundLabel.Text=sprintf("New Maps added: %d",length(maps_added));
            app.DoneButton.Visible="on";
        end

        % Button pushed function: DoneButton
        function DoneButtonPushed(app, event)
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.FilesFoundListBox = uilistbox(app.GridLayout);
            app.FilesFoundListBox.Items = {};
            app.FilesFoundListBox.Value = {};
            app.FilesPathTextAreaLabel = uilabel(app.GridLayout);
            app.FilesPathTextAreaLabel.Text = 'Files Path';
            app.FilesPathTextArea = uitextarea(app.GridLayout);
            app.FilesPathTextArea.Value = {'Valid file names consist of aplhabets only, with the zoom level at the end separated by underscore from main name. For example, satelliteMap_10.mbtiles  means this file contains data for zoom level 10.'};
            app.FindFilesButton = uibutton(app.GridLayout, 'push');
            app.FindFilesButton.ButtonPushedFcn = createCallbackFcn(app, @FindFilesButtonPushed, true);
            app.FindFilesButton.Text = 'Find Files';
            app.FilesFoundLabel = uilabel(app.GridLayout);
            app.FilesFoundLabel.Text = 'Files Found:';
            app.AddMapsButton = uibutton(app.GridLayout, 'push');
            app.AddMapsButton.ButtonPushedFcn = createCallbackFcn(app, @AddMapsButtonPushed, true);
            app.AddMapsButton.Visible = 'off';
            app.AddMapsButton.Text = 'Add Maps';
            app.DoneButton = uibutton(app.GridLayout, 'push');
            app.DoneButton.ButtonPushedFcn = createCallbackFcn(app, @DoneButtonPushed, true);
            app.DoneButton.Visible = 'off';
            app.DoneButton.Text = 'Done';
            app.FilePrefixEditFieldLabel = uilabel(app.GridLayout);
            app.FilePrefixEditFieldLabel.Text = 'File Prefix';
            app.FilePrefixEditField = uieditfield(app.GridLayout, 'text');
            app.FilePrefixEditField.Value = 'satellite';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = basemap_manager

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end