classdef mapSettings < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MapSettingsUIFigure    matlab.ui.Figure
        GridLayout4            matlab.ui.container.GridLayout
        AirwaysPanel           matlab.ui.container.Panel
        GridLayout5_2          matlab.ui.container.GridLayout
        AirwaysColorButton     matlab.ui.control.Button
        AirwaysCheckBox        matlab.ui.control.CheckBox
        LOSCoveragePanel       matlab.ui.container.Panel
        GridLayout6            matlab.ui.container.GridLayout
        Site_1ColorButton      matlab.ui.control.Button
        Site_3ColorButton      matlab.ui.control.Button
        Site_2ColorButton      matlab.ui.control.Button
        OverlapsColorButton    matlab.ui.control.Button
        C_SiteColorButton      matlab.ui.control.Button
        OverlapLabel           matlab.ui.control.Label
        Site3Label             matlab.ui.control.Label
        Site2Label             matlab.ui.control.Label
        Site1Label             matlab.ui.control.Label
        CSiteLabel             matlab.ui.control.Label
        BoundariesPanel        matlab.ui.container.Panel
        GridLayout5            matlab.ui.container.GridLayout
        DistrictsColorButton   matlab.ui.control.Button
        ProvincesColorButton   matlab.ui.control.Button
        CountryColorButton     matlab.ui.control.Button
        DistrictsCheckBox      matlab.ui.control.CheckBox
        ProvincesCheckBox      matlab.ui.control.CheckBox
        CountryCheckBox        matlab.ui.control.CheckBox
        DiscardButton          matlab.ui.control.Button
        SaveButton             matlab.ui.control.Button
        TransportNetworkPanel  matlab.ui.container.Panel
        GridLayout3            matlab.ui.container.GridLayout
        TertiaryColorButton    matlab.ui.control.Button
        SecondaryColorButton   matlab.ui.control.Button
        PrimaryColorButton     matlab.ui.control.Button
        MotorwaysColorButton   matlab.ui.control.Button
        TertiaryCheckBox       matlab.ui.control.CheckBox
        SecondaryCheckBox      matlab.ui.control.CheckBox
        PrimaryCheckBox        matlab.ui.control.CheckBox
        MotorwaysCheckBox      matlab.ui.control.CheckBox
        PlacesPanel            matlab.ui.container.Panel
        GridLayout2            matlab.ui.container.GridLayout
        VillagesColorButton    matlab.ui.control.Button
        TownsColorButton       matlab.ui.control.Button
        CitiesColorButton      matlab.ui.control.Button
        TownsCheckBox          matlab.ui.control.CheckBox
        VillagesCheckBox       matlab.ui.control.CheckBox
        CitiesCheckBox         matlab.ui.control.CheckBox
    end

    
    properties (Access = private)
        places_prefs=[];
        roads_prefs=[];
        boundaries_prefs=[];
        airways_prefs = [];
    end
    
    properties (Access = public)
        map_handle=[]; % Description
        baseapp_handle=[];
    end
    
    methods (Access = private)
        
        function update_gui(app)
            %% update checkboxes
            app.CitiesCheckBox.Value=app.places_prefs.cities_check;
            app.TownsCheckBox.Value=app.places_prefs.towns_check;
            app.VillagesCheckBox.Value=app.places_prefs.villages_check;
            app.MotorwaysCheckBox.Value=app.roads_prefs.motorways_check;
            app.PrimaryCheckBox.Value=app.roads_prefs.primary_check;
            app.SecondaryCheckBox.Value=app.roads_prefs.secondary_check;
            app.TertiaryCheckBox.Value=app.roads_prefs.tertiary_check;
            app.CountryCheckBox.Value=app.boundaries_prefs.country_check;
            app.ProvincesCheckBox.Value=app.boundaries_prefs.provinces_check;
            app.DistrictsCheckBox.Value=app.boundaries_prefs.districts_check;
            app.AirwaysCheckBox.Value=app.airways_prefs.check;
            %% update buttons 
            app.CitiesColorButton.Enable=app.places_prefs.cities_check;
            app.TownsColorButton.Enable=app.places_prefs.towns_check;
            app.VillagesColorButton.Enable=app.places_prefs.villages_check;
            app.MotorwaysColorButton.Enable=app.roads_prefs.motorways_check;
            app.PrimaryColorButton.Enable=app.roads_prefs.primary_check;
            app.SecondaryColorButton.Enable=app.roads_prefs.secondary_check;
            app.TertiaryColorButton.Enable=app.roads_prefs.tertiary_check;
            app.CountryColorButton.Enable=app.boundaries_prefs.country_check;
            app.ProvincesColorButton.Enable=app.boundaries_prefs.provinces_check;
            app.DistrictsColorButton.Enable=app.boundaries_prefs.districts_check;
            app.AirwaysColorButton.Enable = app.airways_prefs.check;

            %% show colors of entities on map
            % places colors
            app.CitiesColorButton.BackgroundColor=app.places_prefs.cities_color;
            app.TownsColorButton.BackgroundColor=app.places_prefs.towns_color;
            app.VillagesColorButton.BackgroundColor=app.places_prefs.villages_color;
            % roads colors
            app.MotorwaysColorButton.BackgroundColor=app.roads_prefs.motorways_color;
            app.PrimaryColorButton.BackgroundColor=app.roads_prefs.primary_color;
            app.SecondaryColorButton.BackgroundColor=app.roads_prefs.secondary_color;
            app.TertiaryColorButton.BackgroundColor=app.roads_prefs.tertiary_color;
            % boundaries colors
            app.CountryColorButton.BackgroundColor=app.boundaries_prefs.country_color;
            app.ProvincesColorButton.BackgroundColor=app.boundaries_prefs.provinces_color;
            app.DistrictsColorButton.BackgroundColor=app.boundaries_prefs.districts_color;
            figure(app.MapSettingsUIFigure);
            app.MapSettingsUIFigure.WindowStyle="normal";
            % Airways colors
            app.AirwaysColorButton.BackgroundColor=app.airways_prefs.color;
            %LOS
            if ~isempty(app.baseapp_handle.plot_handles)
                if isprop(app.baseapp_handle.plot_handles.C_Site{1,1}, 'Visible')
                    app.C_SiteColorButton.BackgroundColor=app.baseapp_handle.plot_handles.C_Site{2}.FaceColor;
                    if app.baseapp_handle.execution_mode==true
                        app.Site_1ColorButton.BackgroundColor=app.baseapp_handle.plot_handles.Site_1{2}.FaceColor;
                        app.Site_2ColorButton.BackgroundColor=app.baseapp_handle.plot_handles.Site_2{2}.FaceColor;
                        app.Site_3ColorButton.BackgroundColor=app.baseapp_handle.plot_handles.Site_3{2}.FaceColor;
                        app.OverlapsColorButton.BackgroundColor=app.baseapp_handle.plot_handles.Overlaps{1}.FaceColor;
                    end
                end
            end

        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            app.MapSettingsUIFigure.Visible="off";
            movegui(app.MapSettingsUIFigure,'center');
            drawnow;
            if isempty(varargin)
            % Places
            app.places_prefs.cities_check=false;
            app.places_prefs.towns_check=true;
            app.places_prefs.villages_check=false;
            app.places_prefs.cities_color=[146 248 245]/256;
            app.places_prefs.towns_color=[0 255 64]/256;
            app.places_prefs.villages_color=[255 255 255]/256;
            % Roads
            app.roads_prefs.primary_check=true;
            app.roads_prefs.secondary_check=true;
            app.roads_prefs.tertiary_check=true;
            app.roads_prefs.motorways_check=true;
            
            app.roads_prefs.primary_color=[254 201 14]/255;
            app.roads_prefs.secondary_color=[20 37 248]/255;
            app.roads_prefs.tertiary_color=[255 174 201]/256;
            app.roads_prefs.motorways_color='g';

            % Boundaries
            app.boundaries_prefs.country_check=false;
            app.boundaries_prefs.provinces_check=false;
            app.boundaries_prefs.districts_check=false;

            app.boundaries_prefs.country_color='k';
            app.boundaries_prefs.provinces_color='k';
            app.boundaries_prefs.districts_color='k';

            % Airways
            app.airways_prefs.check=false;
            app.airways_prefs.color = [0.77 0.77 0.77];
            else
            app.map_handle=varargin{1};
            app.places_prefs=app.map_handle.UserData.places_prefs;
            app.roads_prefs=app.map_handle.UserData.roads_prefs;
            app.boundaries_prefs=app.map_handle.UserData.boundaries_prefs;
            app.airways_prefs = app.map_handle.UserData.airways_prefs;
            app.baseapp_handle=varargin{2};
            end 
            if app.baseapp_handle.execution_mode==false % radar mode
                app.Site_1ColorButton.Enable="off";
                app.Site_2ColorButton.Enable="off";
                app.Site_3ColorButton.Enable="off";
                app.OverlapsColorButton.Enable="off";
                app.Site1Label.Enable="off";
                app.Site3Label.Enable="off";
                app.Site3Label.Enable="off";
                app.OverlapLabel.Enable="off";
            end
            if ~isempty(app.baseapp_handle.plot_handles)
                if ~isprop(app.baseapp_handle.plot_handles.C_Site{1,1}, 'Visible')
                    app.LOSCoveragePanel.Visible='off';
                else
                    app.LOSCoveragePanel.Visible='on';
                end
            else
                app.LOSCoveragePanel.Visible='off';
            end
            app.MapSettingsUIFigure.Visible="on";
            app.update_gui;
        end

        % Button pushed function: CitiesColorButton, TownsColorButton, 
        % ...and 1 other component
        function CitiesColorButtonPushed(app, event)
            color=uisetcolor;
            if ~(length(color)<2)
            tag=event.Source.Tag;
            field_name=[lower(tag) '_color'];
            btn_name=[tag 'ColorButton'];
            app.places_prefs.(field_name)=color;
            app.(btn_name).BackgroundColor=color;
            end
            figure(app.MapSettingsUIFigure);
        end

        % Button pushed function: MotorwaysColorButton, 
        % ...and 3 other components
        function MotorwaysColorButtonPushed(app, event)
            color=uisetcolor;
            if ~(length(color)<2)
            tag=event.Source.Tag;
            field_name=[lower(tag) '_color'];
            btn_name=[tag 'ColorButton'];
            app.roads_prefs.(field_name)=color;
            app.(btn_name).BackgroundColor=color;
            end
            figure(app.MapSettingsUIFigure);
        end

        % Button pushed function: CountryColorButton, 
        % ...and 2 other components
        function CountryColorButtonPushed(app, event)
            color=uisetcolor;
            if ~(length(color)<2)
            tag=event.Source.Tag;
            field_name=[lower(tag) '_color'];
            btn_name=[tag 'ColorButton'];
            app.boundaries_prefs.(field_name)=color;
            app.(btn_name).BackgroundColor=color;
            end
            figure(app.MapSettingsUIFigure);
        end

        % Button pushed function: DiscardButton
        function DiscardButtonPushed(app, event)
            delete(app)
        end

        % Value changed function: CitiesCheckBox, TownsCheckBox, 
        % ...and 1 other component
        function CitiesCheckBoxValueChanged(app, event)
            tag=event.Source.Tag;
            caller=[tag 'CheckBox'];
            value = app.(caller).Value;
            field_name=[lower(tag) '_check'];
            app.places_prefs.(field_name)=value;
            btn_name=[tag 'ColorButton'];
            app.(btn_name).Enable=value;
        end

        % Value changed function: MotorwaysCheckBox, PrimaryCheckBox, 
        % ...and 2 other components
        function MotorwaysCheckBoxValueChanged(app, event)
            tag=event.Source.Tag;
            caller=[tag 'CheckBox'];
            value = app.(caller).Value;
            field_name=[lower(tag) '_check'];
            app.roads_prefs.(field_name)=value;
            btn_name=[tag 'ColorButton'];
            app.(btn_name).Enable=value;
        end

        % Value changed function: CountryCheckBox, DistrictsCheckBox, 
        % ...and 1 other component
        function CountryCheckBoxValueChanged(app, event)
            tag=event.Source.Tag;
            caller=[tag 'CheckBox'];
            value = app.(caller).Value;
            field_name=[lower(tag) '_check'];
            app.boundaries_prefs.(field_name)=value;
            btn_name=[tag 'ColorButton'];
            app.(btn_name).Enable=value;
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            if ~isempty(app.map_handle)
                app.map_handle.UserData.places_prefs=app.places_prefs;
                app.map_handle.UserData.placesUpdated = 1;
                app.map_handle.UserData.roads_prefs=app.roads_prefs;
                app.map_handle.UserData.boundaries_prefs=app.boundaries_prefs;
                app.map_handle.UserData.airways_prefs=app.airways_prefs;
                if isfield(app.map_handle.UserData, 'bckgnd_service')
                    f = app.map_handle.UserData.bckgnd_service;
                    if ~isempty(f) && isvalid(f) && ~strcmp(f.State, 'finished')
                        cancel(f);
                    end
                end

                %% Update Map services
                % Get current preferences
                app.roads_prefs = app.map_handle.UserData.roads_prefs;
                app.places_prefs = app.map_handle.UserData.places_prefs;
                app.boundaries_prefs = app.map_handle.UserData.boundaries_prefs;
                app.airways_prefs = app.map_handle.UserData.airways_prefs;

                % Conditionally start services based on what is enabled
                % For Roads:
                if isstruct(app.roads_prefs)
                    delete(app.map_handle.UserData.roads.motorway);
                    delete(app.map_handle.UserData.roads.tertiary);
                    delete(app.map_handle.UserData.roads.secondary);
                    delete(app.map_handle.UserData.roads.primary);
                    create_roads_service(app.map_handle);
                end

                % For Places (Cities, Towns, Villages):
                % Create a similar service function for places.
                if isstruct(app.places_prefs)
                    delete(app.map_handle.UserData.cities);
                    delete(app.map_handle.UserData.towns.txt);
                    delete(app.map_handle.UserData.towns.pts);
                    delete(app.map_handle.UserData.villages.txt);
                    delete(app.map_handle.UserData.villages.pts);
                    create_cities_service(app.map_handle);
                end

                % For Boundaries:
                %Create a similar service function for boundaries.
                if isstruct(app.boundaries_prefs)
                    create_boundaries_service(app.map_handle);
                end
                
                % For Airways:
                %Create a similar service function for Airways.
                if isstruct(app.airways_prefs)
                    delete(app.map_handle.UserData.airways);
                    create_airways_service(app.map_handle);
                end
            end

            delete(app);
        end

        % Close request function: MapSettingsUIFigure
        function MapSettingsUIFigureCloseRequest(app, event)
            
        end

        % Button pushed function: C_SiteColorButton, OverlapsColorButton, 
        % ...and 3 other components
        function C_SiteColorButtonPushed(app, event)
            color=uisetcolor;
            tag=event.Source.Tag;
           
            if ~(length(color)<2)
                if ~isempty(app.baseapp_handle.plot_handles)
                    site_name=tag;
                    site_data=app.baseapp_handle.plot_handles.(site_name);
                    % for the site markers
                    if ~strcmp(tag,"Overlaps")
                        site_data{1,1}.MarkerFaceColor=color;
                        site_data{1,1}.MarkerEdgeColor='k';
                    end
                   %  for polygon
                    for data_index=1:length(site_data)
                        plot_handle=site_data{data_index};
                        if isa(plot_handle, 'map.graphics.chart.primitive.Polygon')
                            plot_handle.FaceColor=color;
                            plot_handle.EdgeColor=color;
                        % elseif strcmp(plot_handle.Tag,'Site_marker')
                        end
                    end
                    btn_name=[tag 'ColorButton'];
                    app.(btn_name).BackgroundColor=color;
                end

            end
            figure(app.MapSettingsUIFigure);
        end

        % Button pushed function: AirwaysColorButton
        function AirwaysColorButtonPushed(app, event)
            color=uisetcolor;
            if ~(length(color)<2)
                tag=event.Source.Tag;
                btn_name=[tag 'ColorButton'];
                app.airways_prefs.color=color;
                app.(btn_name).BackgroundColor=color;
            end
            figure(app.MapSettingsUIFigure);
        end

        % Value changed function: AirwaysCheckBox
        function AirwaysCheckBoxValueChanged(app, event)
            tag=event.Source.Tag;
            caller=[tag 'CheckBox'];
            value = app.(caller).Value;
            app.airways_prefs.check=value;
            btn_name=[tag 'ColorButton'];
            app.(btn_name).Enable=value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MapSettingsUIFigure = uifigure('Visible', 'off');
            app.MapSettingsUIFigure.Name = 'Map Settings';
            app.MapSettingsUIFigure.Icon = 'icon_nav.png';
            app.MapSettingsUIFigure.CloseRequestFcn = createCallbackFcn(app, @MapSettingsUIFigureCloseRequest, true);
            app.GridLayout4 = uigridlayout(app.MapSettingsUIFigure);
            app.PlacesPanel = uipanel(app.GridLayout4);
            app.PlacesPanel.Title = 'Places';
            app.GridLayout2 = uigridlayout(app.PlacesPanel);
            app.CitiesCheckBox = uicheckbox(app.GridLayout2);
            app.CitiesCheckBox.ValueChangedFcn = createCallbackFcn(app, @CitiesCheckBoxValueChanged, true);
            app.CitiesCheckBox.Tag = 'Cities';
            app.CitiesCheckBox.Text = 'Cities';
            app.VillagesCheckBox = uicheckbox(app.GridLayout2);
            app.VillagesCheckBox.ValueChangedFcn = createCallbackFcn(app, @CitiesCheckBoxValueChanged, true);
            app.VillagesCheckBox.Tag = 'Villages';
            app.VillagesCheckBox.Text = 'Villages/Subrubs';
            app.TownsCheckBox = uicheckbox(app.GridLayout2);
            app.TownsCheckBox.ValueChangedFcn = createCallbackFcn(app, @CitiesCheckBoxValueChanged, true);
            app.TownsCheckBox.Tag = 'Towns';
            app.TownsCheckBox.Text = 'Towns';
            app.CitiesColorButton = uibutton(app.GridLayout2, 'push');
            app.CitiesColorButton.ButtonPushedFcn = createCallbackFcn(app, @CitiesColorButtonPushed, true);
            app.CitiesColorButton.Tag = 'Cities';
            app.CitiesColorButton.Text = '';
            app.TownsColorButton = uibutton(app.GridLayout2, 'push');
            app.TownsColorButton.ButtonPushedFcn = createCallbackFcn(app, @CitiesColorButtonPushed, true);
            app.TownsColorButton.Tag = 'Towns';
            app.TownsColorButton.Text = '';
            app.VillagesColorButton = uibutton(app.GridLayout2, 'push');
            app.VillagesColorButton.ButtonPushedFcn = createCallbackFcn(app, @CitiesColorButtonPushed, true);
            app.VillagesColorButton.Tag = 'Villages';
            app.VillagesColorButton.Text = '';
            app.TransportNetworkPanel = uipanel(app.GridLayout4);
            app.TransportNetworkPanel.Title = 'Transport Network';
            app.GridLayout3 = uigridlayout(app.TransportNetworkPanel);
            app.MotorwaysCheckBox = uicheckbox(app.GridLayout3);
            app.MotorwaysCheckBox.ValueChangedFcn = createCallbackFcn(app, @MotorwaysCheckBoxValueChanged, true);
            app.MotorwaysCheckBox.Tag = 'Motorways';
            app.MotorwaysCheckBox.Text = 'Motorways';
            app.PrimaryCheckBox = uicheckbox(app.GridLayout3);
            app.PrimaryCheckBox.ValueChangedFcn = createCallbackFcn(app, @MotorwaysCheckBoxValueChanged, true);
            app.PrimaryCheckBox.Tag = 'Primary';
            app.PrimaryCheckBox.Text = 'Country Wide';
            app.SecondaryCheckBox = uicheckbox(app.GridLayout3);
            app.SecondaryCheckBox.ValueChangedFcn = createCallbackFcn(app, @MotorwaysCheckBoxValueChanged, true);
            app.SecondaryCheckBox.Tag = 'Secondary';
            app.SecondaryCheckBox.Text = 'District Wide';
            app.TertiaryCheckBox = uicheckbox(app.GridLayout3);
            app.TertiaryCheckBox.ValueChangedFcn = createCallbackFcn(app, @MotorwaysCheckBoxValueChanged, true);
            app.TertiaryCheckBox.Tag = 'Tertiary';
            app.TertiaryCheckBox.Text = 'Local Roads';
            app.MotorwaysColorButton = uibutton(app.GridLayout3, 'push');
            app.MotorwaysColorButton.ButtonPushedFcn = createCallbackFcn(app, @MotorwaysColorButtonPushed, true);
            app.MotorwaysColorButton.Tag = 'Motorways';
            app.MotorwaysColorButton.Text = '';
            app.PrimaryColorButton = uibutton(app.GridLayout3, 'push');
            app.PrimaryColorButton.ButtonPushedFcn = createCallbackFcn(app, @MotorwaysColorButtonPushed, true);
            app.PrimaryColorButton.Tag = 'Primary';
            app.PrimaryColorButton.Text = '';
            app.SecondaryColorButton = uibutton(app.GridLayout3, 'push');
            app.SecondaryColorButton.ButtonPushedFcn = createCallbackFcn(app, @MotorwaysColorButtonPushed, true);
            app.SecondaryColorButton.Tag = 'Secondary';
            app.SecondaryColorButton.Text = '';
            app.TertiaryColorButton = uibutton(app.GridLayout3, 'push');
            app.TertiaryColorButton.ButtonPushedFcn = createCallbackFcn(app, @MotorwaysColorButtonPushed, true);
            app.TertiaryColorButton.Tag = 'Tertiary';
            app.TertiaryColorButton.Text = '';
            app.SaveButton = uibutton(app.GridLayout4, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.DiscardButton = uibutton(app.GridLayout4, 'push');
            app.DiscardButton.ButtonPushedFcn = createCallbackFcn(app, @DiscardButtonPushed, true);
            app.DiscardButton.Text = 'Discard';
            app.BoundariesPanel = uipanel(app.GridLayout4);
            app.BoundariesPanel.Title = 'Boundaries';
            app.GridLayout5 = uigridlayout(app.BoundariesPanel);
            app.CountryCheckBox = uicheckbox(app.GridLayout5);
            app.CountryCheckBox.ValueChangedFcn = createCallbackFcn(app, @CountryCheckBoxValueChanged, true);
            app.CountryCheckBox.Tag = 'Country';
            app.CountryCheckBox.Text = 'Country';
            app.ProvincesCheckBox = uicheckbox(app.GridLayout5);
            app.ProvincesCheckBox.ValueChangedFcn = createCallbackFcn(app, @CountryCheckBoxValueChanged, true);
            app.ProvincesCheckBox.Tag = 'Provinces';
            app.ProvincesCheckBox.Text = 'Provincial';
            app.DistrictsCheckBox = uicheckbox(app.GridLayout5);
            app.DistrictsCheckBox.ValueChangedFcn = createCallbackFcn(app, @CountryCheckBoxValueChanged, true);
            app.DistrictsCheckBox.Tag = 'Districts';
            app.DistrictsCheckBox.Text = 'Districts';
            app.CountryColorButton = uibutton(app.GridLayout5, 'push');
            app.CountryColorButton.ButtonPushedFcn = createCallbackFcn(app, @CountryColorButtonPushed, true);
            app.CountryColorButton.Tag = 'Country';
            app.CountryColorButton.Text = '';
            app.ProvincesColorButton = uibutton(app.GridLayout5, 'push');
            app.ProvincesColorButton.ButtonPushedFcn = createCallbackFcn(app, @CountryColorButtonPushed, true);
            app.ProvincesColorButton.Tag = 'Provinces';
            app.ProvincesColorButton.Text = '';
            app.DistrictsColorButton = uibutton(app.GridLayout5, 'push');
            app.DistrictsColorButton.ButtonPushedFcn = createCallbackFcn(app, @CountryColorButtonPushed, true);
            app.DistrictsColorButton.Tag = 'Districts';
            app.DistrictsColorButton.Text = '';
            app.LOSCoveragePanel = uipanel(app.GridLayout4);
            app.LOSCoveragePanel.Title = 'LOS Coverage';
            app.GridLayout6 = uigridlayout(app.LOSCoveragePanel);
            app.CSiteLabel = uilabel(app.GridLayout6);
            app.CSiteLabel.Text = 'C Site';
            app.Site1Label = uilabel(app.GridLayout6);
            app.Site1Label.Text = 'Site 1';
            app.Site2Label = uilabel(app.GridLayout6);
            app.Site2Label.Text = 'Site 2 ';
            app.Site3Label = uilabel(app.GridLayout6);
            app.Site3Label.Text = 'Site 3';
            app.OverlapLabel = uilabel(app.GridLayout6);
            app.OverlapLabel.Text = 'Overlap';
            app.C_SiteColorButton = uibutton(app.GridLayout6, 'push');
            app.C_SiteColorButton.ButtonPushedFcn = createCallbackFcn(app, @C_SiteColorButtonPushed, true);
            app.C_SiteColorButton.Tag = 'C_Site';
            app.C_SiteColorButton.Text = '';
            app.OverlapsColorButton = uibutton(app.GridLayout6, 'push');
            app.OverlapsColorButton.ButtonPushedFcn = createCallbackFcn(app, @C_SiteColorButtonPushed, true);
            app.OverlapsColorButton.Tag = 'Overlaps';
            app.OverlapsColorButton.Text = '';
            app.Site_2ColorButton = uibutton(app.GridLayout6, 'push');
            app.Site_2ColorButton.ButtonPushedFcn = createCallbackFcn(app, @C_SiteColorButtonPushed, true);
            app.Site_2ColorButton.Tag = 'Site_2';
            app.Site_2ColorButton.Text = '';
            app.Site_3ColorButton = uibutton(app.GridLayout6, 'push');
            app.Site_3ColorButton.ButtonPushedFcn = createCallbackFcn(app, @C_SiteColorButtonPushed, true);
            app.Site_3ColorButton.Tag = 'Site_3';
            app.Site_3ColorButton.Text = '';
            app.Site_1ColorButton = uibutton(app.GridLayout6, 'push');
            app.Site_1ColorButton.ButtonPushedFcn = createCallbackFcn(app, @C_SiteColorButtonPushed, true);
            app.Site_1ColorButton.Tag = 'Site_1';
            app.Site_1ColorButton.Text = '';
            app.AirwaysPanel = uipanel(app.GridLayout4);
            app.AirwaysPanel.Title = 'Airways';
            app.GridLayout5_2 = uigridlayout(app.AirwaysPanel);
            app.AirwaysCheckBox = uicheckbox(app.GridLayout5_2);
            app.AirwaysCheckBox.ValueChangedFcn = createCallbackFcn(app, @AirwaysCheckBoxValueChanged, true);
            app.AirwaysCheckBox.Tag = 'Airways';
            app.AirwaysCheckBox.Text = 'Airways';
            app.AirwaysColorButton = uibutton(app.GridLayout5_2, 'push');
            app.AirwaysColorButton.ButtonPushedFcn = createCallbackFcn(app, @AirwaysColorButtonPushed, true);
            app.AirwaysColorButton.Tag = 'Airways';
            app.AirwaysColorButton.Text = '';
            % Show the figure after all components are created
            app.MapSettingsUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mapSettings(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MapSettingsUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MapSettingsUIFigure)
        end
    end
end