classdef map_point_picker < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PlatformSelectorUIFigure  matlab.ui.Figure
        LongitudeEditField        matlab.ui.control.NumericEditField
        LongitudeEditFieldLabel   matlab.ui.control.Label
        LatitudeEditField         matlab.ui.control.NumericEditField
        LatitudeEditFieldLabel    matlab.ui.control.Label
        StatusLabel               matlab.ui.control.Label
        CancelButton              matlab.ui.control.Button
        OkButton                  matlab.ui.control.Button
        NameEditField             matlab.ui.control.EditField
        NameEditFieldLabel        matlab.ui.control.Label
        SiteMarkersListBox        matlab.ui.control.ListBox
        SiteMarkersonMapLabel     matlab.ui.control.Label
    end

    
    properties (Access = public)
        value_selected logical = false;
        AvailablePoints
        base_app
        lat_field
        lon_field
        name_field
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            app.PlatformSelectorUIFigure.Visible="off";
            app.PlatformSelectorUIFigure.WindowStyle="normal";
            movegui(app.PlatformSelectorUIFigure,"center");
            % names={};
            % points=[];
            % all_points_used=true;
            % no_points_on_map=true;
            % verify function called from base app
            % if ~isempty(varargin)
            %     % store handle for base app
                app.base_app=varargin{1};
            %     % get points placed on map
            %     survey_points=app.base_app.survey_pts_on_map;
            %     if ~isempty(survey_points)
            %         p=[];
            %         p.name='';
            %         p.lat=0;
            %         p.lon=0;
            %         % init points with struct p to allow rutime array
            %         % expansion, otherwise, runtime errors may occur.
            %         points=p;
            %         no_points_on_map=false;
            %         i=1;
            %         for index=1:length(survey_points)
            %             element=survey_points(index);
            %             % If point has not already been picked in report
            %             if ~element.UserData.is_picked
            %                 p.name=element.UserData.point_name.String{1};
            %                 p.lat=element.LatitudeData;
            %                 p.lon=element.LongitudeData;
            %                 names{i}=p.name;
            %                 points(i)=p;
            %                 i=i+1;
            %                 all_points_used=false;
            %             end
            %         end
            %     end
            % end
            % place data in list box
            % app.SiteMarkersListBox.Items=names;
            % app.SiteMarkersListBox.ItemsData=points;
            % if no_points_on_map
            %     % if no points availble in map, let user know
            %     app.StatusLabel.Text='No points placed on map!';
            %     app.value_selected=false;
            %     app.OkButton.Enable="off";
            % else
            %     if all_points_used
            %         % points placed on map, but all points selected for some
            %         % site
            %         app.StatusLabel.Text='All available points have been already selected, place new points on map!';
            %         app.value_selected=false;
            %         app.OkButton.Enable="off";
            %     else
            %         % points availbe for new selection
            %         app.StatusLabel.Text='Select one of points availbe on map and click ok';
            %         app.value_selected=true;
            %         app.OkButton.Enable="on";
            %         % populate fields with first point available in list
            %         point1=app.SiteMarkersListBox.Value;
            %         app.LatitudeEditField.Value=point1.lat;
            %         app.LongitudeEditField.Value=point1.lon;
            %         app.NameEditField.Value=point1.name;
            %     end
            % end
            app.PlatformSelectorUIFigure.Visible="on";
        end

        % Value changed function: SiteMarkersListBox
        function SiteMarkersListBoxValueChanged(app, event)
            idx = app.SiteMarkersListBox.ValueIndex;

            if idx > 0
                point = app.AvailablePoints(idx);

                app.LatitudeEditField.Value = point.LatitudeData;
                app.LongitudeEditField.Value = point.LongitudeData;
                app.NameEditField.Value = point.UserData.point_name.String{1};

                app.value_selected = true;
                app.OkButton.Enable = 'on';

            end
        end

        % Button pushed function: OkButton
        function OkButtonPushed(app, event)
            % extra check, may be removed if ensured that OK is enabled
            % only when data is availabled.
            if ~isempty(app.SiteMarkersListBox.Value)
                app.value_selected = true;
            else
                app.value_selected = false;
            end

            app.base_app.processPickedPoint();

            app.PlatformSelectorUIFigure.Visible="off";


        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            % delete(app)
            app.PlatformSelectorUIFigure.Visible="off";

        end

        % Close request function: PlatformSelectorUIFigure
        function PlatformSelectorUIFigureCloseRequest(app, event)
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.PlatformSelectorUIFigure = uifigure('Visible', 'off');
            app.PlatformSelectorUIFigure.Name = 'Platform Selector';
            app.PlatformSelectorUIFigure.Icon = 'icon_nav.png';
            app.PlatformSelectorUIFigure.CloseRequestFcn = createCallbackFcn(app, @PlatformSelectorUIFigureCloseRequest, true);
            app.SiteMarkersonMapLabel = uilabel(app.PlatformSelectorUIFigure);
            app.SiteMarkersonMapLabel.Text = 'Site Markers on Map';
            app.SiteMarkersListBox = uilistbox(app.PlatformSelectorUIFigure);
            app.SiteMarkersListBox.Items = {'Item 4'};
            app.SiteMarkersListBox.ValueChangedFcn = createCallbackFcn(app, @SiteMarkersListBoxValueChanged, true);
            app.SiteMarkersListBox.Value = 'Item 4';
            app.NameEditFieldLabel = uilabel(app.PlatformSelectorUIFigure);
            app.NameEditFieldLabel.Text = 'Name';
            app.NameEditField = uieditfield(app.PlatformSelectorUIFigure, 'text');
            app.OkButton = uibutton(app.PlatformSelectorUIFigure, 'push');
            app.OkButton.ButtonPushedFcn = createCallbackFcn(app, @OkButtonPushed, true);
            app.OkButton.Enable = 'off';
            app.OkButton.Text = 'Ok';
            app.CancelButton = uibutton(app.PlatformSelectorUIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.StatusLabel = uilabel(app.PlatformSelectorUIFigure);
            app.StatusLabel.Text = 'Status';
            app.LatitudeEditFieldLabel = uilabel(app.PlatformSelectorUIFigure);
            app.LatitudeEditFieldLabel.Text = 'Latitude';
            app.LatitudeEditField = uieditfield(app.PlatformSelectorUIFigure, 'numeric');
            app.LatitudeEditField.ValueDisplayFormat = '%.6f';
            app.LongitudeEditFieldLabel = uilabel(app.PlatformSelectorUIFigure);
            app.LongitudeEditFieldLabel.Text = 'Longitude';
            app.LongitudeEditField = uieditfield(app.PlatformSelectorUIFigure, 'numeric');
            app.LongitudeEditField.ValueDisplayFormat = '%.6f';
            % Show the figure after all components are created
            app.PlatformSelectorUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = map_point_picker(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.PlatformSelectorUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PlatformSelectorUIFigure)
        end
    end
end