classdef date_time_picker < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PickDateandTimeUIFigure  matlab.ui.Figure
        GridLayout               matlab.ui.container.GridLayout
        OkButton                 matlab.ui.control.Button
        TimeLabel                matlab.ui.control.Label
        Label                    matlab.ui.control.Label
        DateDatePicker           matlab.ui.control.DatePicker
        DateDatePickerLabel      matlab.ui.control.Label
        MinutesDropDown          matlab.ui.control.DropDown
        MinutesDropDownLabel     matlab.ui.control.Label
        HoursDropDown            matlab.ui.control.DropDown
        HoursDropDownLabel       matlab.ui.control.Label
        Image                    matlab.ui.control.Image
    end

    
    properties (Access = private)
        base_app_handle=[]; % handle to caller app
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            base_app=[];
            if ~isempty(varargin)
                base_app=varargin{1};
            end
            app.PickDateandTimeUIFigure.WindowStyle="modal";
            app.PickDateandTimeUIFigure.Units="normalized";
            app.PickDateandTimeUIFigure.Position=[0.4 0.4 0.1453 0.1657];
            app.PickDateandTimeUIFigure.Units="pixels";
            hours=1:1:23;
            mins=1:1:59;
            app.HoursDropDown.Items=string(hours);
            app.MinutesDropDown.Items=string(mins);
            if ~isempty(base_app)
                app.base_app_handle=base_app;

                app.base_app_handle.continue_calculation=false;
            end
            % img=imread('icons/calendar2.png');
            % axes(app.UIAxes);
            % h=imshow(img,'Parent',app.UIAxes);
        end

        % Button pushed function: OkButton
        function OkButtonPushed(app, event)
            d=[app.HoursDropDown.Value ':' app.MinutesDropDown.Value ':0'];
            t=app.DateDatePicker.Value;
            if isnat(t)
                t='11-Sep-2023';
            end
            t_=datestr(t);
            final_data=[t_ ' ' d];
            date_time=datetime(final_data);
            x=0;
            if ~isempty(app)
                app.base_app_handle.time_selected=date_time;
                app.base_app_handle.continue_calculation=true;
            end
            delete(app);
        end

        % Close request function: PickDateandTimeUIFigure
        function PickDateandTimeUIFigureCloseRequest(app, event)
            if ~isempty(app)
                app.base_app_handle.time_selected=[];
                app.base_app_handle.continue_calculation=true;
            end
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.PickDateandTimeUIFigure = uifigure('Visible', 'off');
            app.PickDateandTimeUIFigure.Name = 'Pick Date and Time';
            app.PickDateandTimeUIFigure.Icon = 'schedule.png';
            app.PickDateandTimeUIFigure.CloseRequestFcn = createCallbackFcn(app, @PickDateandTimeUIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.PickDateandTimeUIFigure);
            app.Image = uiimage(app.GridLayout);
            app.Image.ImageSource = 'schedule.png';
            app.HoursDropDownLabel = uilabel(app.GridLayout);
            app.HoursDropDownLabel.Text = 'Hours';
            app.HoursDropDown = uidropdown(app.GridLayout);
            app.MinutesDropDownLabel = uilabel(app.GridLayout);
            app.MinutesDropDownLabel.Text = 'Minutes';
            app.MinutesDropDown = uidropdown(app.GridLayout);
            app.DateDatePickerLabel = uilabel(app.GridLayout);
            app.DateDatePickerLabel.Text = 'Date';
            app.DateDatePicker = uidatepicker(app.GridLayout);
            app.Label = uilabel(app.GridLayout);
            app.Label.Text = ':';
            app.TimeLabel = uilabel(app.GridLayout);
            app.TimeLabel.Text = 'Time';
            app.OkButton = uibutton(app.GridLayout, 'push');
            app.OkButton.ButtonPushedFcn = createCallbackFcn(app, @OkButtonPushed, true);
            app.OkButton.Icon = 'icons8-ok-button-40.png';
            app.OkButton.Text = 'Ok';
            % Show the figure after all components are created
            app.PickDateandTimeUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = date_time_picker(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.PickDateandTimeUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PickDateandTimeUIFigure)
        end
    end
end