classdef gdop_popup < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        CompleteButton                matlab.ui.control.Button
        Timingerrorlabel_3            matlab.ui.control.Label
        Timingerrorlabel_2            matlab.ui.control.Label
        ContourLevelsMin              matlab.ui.control.NumericEditField
        ContourLevelsMax              matlab.ui.control.NumericEditField
        ContourLevelsEditFieldLabel   matlab.ui.control.Label
        resolutionlabel               matlab.ui.control.Label
        Resolutiongdop                matlab.ui.control.NumericEditField
        ResolutionEditFieldLabel      matlab.ui.control.Label
        Timingerrorlabel              matlab.ui.control.Label
        TimingErrorgdop               matlab.ui.control.NumericEditField
        TimingErrorEditFieldLabel     matlab.ui.control.Label
        ReferenceSensorgdop           matlab.ui.control.DropDown
        ReferenceSensorDropDownLabel  matlab.ui.control.Label
        Panel_2                       matlab.ui.container.Panel
        Panel                         matlab.ui.container.Panel
        Panel_4                       matlab.ui.container.Panel
        Panel_5                       matlab.ui.container.Panel
        TDoAConfigurationLabel        matlab.ui.control.Label
        Panel_6                       matlab.ui.container.Panel
    end


    properties (Access = private)
        base_app_handle=[]; % handle to caller app
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            app.UIFigure.Visible = "off";
            app.UIFigure.Name = 'TDoA Accuracy Setup';
            base_app=[];
            movegui(app.UIFigure,"center");


            if ~isempty(varargin)
                base_app=varargin{1};
            end
            if ~isempty(base_app)
                app.base_app_handle=base_app;
            end

            try
                if ~isempty(app.base_app_handle.current_survey_data)
                    app.ReferenceSensorgdop.Items{1} =  app.base_app_handle.current_survey_data{1}.csite_checklist{1}.site_name;
                    app.ReferenceSensorgdop.Items{2} = app.base_app_handle.current_survey_data{1}.site1_checklist{1}.site_name;
                    app.ReferenceSensorgdop.Items{3} = app.base_app_handle.current_survey_data{1}.site2_checklist{1}.site_name;
                    app.ReferenceSensorgdop.Items{4} = app.base_app_handle.current_survey_data{1}.site3_checklist{1}.site_name;
                elseif ~isempty(app.base_app_handle.csite.survey_sites)
                    app.ReferenceSensorgdop.Items{1} = app.base_app_handle.csite.survey_sites{2}.site_name;
                    app.ReferenceSensorgdop.Items{2} = app.base_app_handle.site1.survey_sites{2}.site_name;
                    app.ReferenceSensorgdop.Items{3} = app.base_app_handle.site2.survey_sites{2}.site_name;
                    app.ReferenceSensorgdop.Items{4} = app.base_app_handle.site3.survey_sites{2}.site_name;
                else
                    warndlg('Assign sites before calculating GDoP')
                    updateText(app.base_app_handle,'No sites assigned for survey. Please select or place sites in LoS tab.')
                    return
                end
            catch
            end
            
            app.UIFigure.Visible = "on";
            app.UIFigure.WindowStyle = "modal";
        end

        % Button pushed function: CompleteButton
        function CompleteButtonPushed(app, event)
            if ~isempty(app)
                app.base_app_handle.gdop.refsen=app.ReferenceSensorgdop.ValueIndex;
                app.base_app_handle.gdop.timingerr=app.TimingErrorgdop.Value*1e-9;
                app.base_app_handle.gdop.resolution=app.Resolutiongdop.Value;
                app.base_app_handle.gdop.conLevelmin=app.ContourLevelsMin.Value;
                app.base_app_handle.gdop.conLevelmax=app.ContourLevelsMax.Value;
                
                %% Update the log panel
                refsensLog = sprintf('Reference sensor selected: %s',app.ReferenceSensorgdop.Value);
                updateText(app.base_app_handle,refsensLog);

                TimingerrLog = sprintf('Updated timing error for TDoA Accuracy: %d (ns)',app.base_app_handle.gdop.timingerr*1e9);
                updateText(app.base_app_handle,TimingerrLog);

                resolutionLog = sprintf('TDoA accuracy grid resolution: %d (m)',app.base_app_handle.gdop.resolution);
                updateText(app.base_app_handle,resolutionLog);
                
                contlevLog = sprintf('Contour level intervals: %d-%d (m)',app.base_app_handle.gdop.conLevelmin,app.base_app_handle.gdop.conLevelmax);
                updateText(app.base_app_handle,contlevLog);

                app.base_app_handle.continue_calculation = true;
            end
            
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Panel_6 = uipanel(app.UIFigure);
            app.Panel_5 = uipanel(app.UIFigure);
            app.TDoAConfigurationLabel = uilabel(app.Panel_5);
            app.TDoAConfigurationLabel.Text = 'TDoA Configuration';
            app.Panel_4 = uipanel(app.UIFigure);
            app.Panel_4.Enable = 'off';
            app.Panel_4.Visible = 'off';
            app.Panel = uipanel(app.UIFigure);
            app.Panel_2 = uipanel(app.UIFigure);
            app.ReferenceSensorDropDownLabel = uilabel(app.UIFigure);
            app.ReferenceSensorDropDownLabel.Text = 'Reference Sensor';
            app.ReferenceSensorgdop = uidropdown(app.UIFigure);
            app.TimingErrorEditFieldLabel = uilabel(app.UIFigure);
            app.TimingErrorEditFieldLabel.Text = 'Timing Error';
            app.TimingErrorgdop = uieditfield(app.UIFigure, 'numeric');
            app.TimingErrorgdop.Value = 50;
            app.Timingerrorlabel = uilabel(app.UIFigure);
            app.Timingerrorlabel.Text = 'ns';
            app.ResolutionEditFieldLabel = uilabel(app.UIFigure);
            app.ResolutionEditFieldLabel.Text = 'Resolution';
            app.Resolutiongdop = uieditfield(app.UIFigure, 'numeric');
            app.Resolutiongdop.ValueDisplayFormat = '%.0f';
            app.Resolutiongdop.Value = 5000;
            app.resolutionlabel = uilabel(app.UIFigure);
            app.resolutionlabel.Text = 'm';
            app.ContourLevelsEditFieldLabel = uilabel(app.UIFigure);
            app.ContourLevelsEditFieldLabel.Enable = 'off';
            app.ContourLevelsEditFieldLabel.Visible = 'off';
            app.ContourLevelsEditFieldLabel.Text = 'Contour Levels';
            app.ContourLevelsMax = uieditfield(app.UIFigure, 'numeric');
            app.ContourLevelsMax.ValueDisplayFormat = '%.0f';
            app.ContourLevelsMax.Enable = 'off';
            app.ContourLevelsMax.Visible = 'off';
            app.ContourLevelsMax.Value = 5000;
            app.ContourLevelsMin = uieditfield(app.UIFigure, 'numeric');
            app.ContourLevelsMin.ValueDisplayFormat = '%.0f';
            app.ContourLevelsMin.Enable = 'off';
            app.ContourLevelsMin.Visible = 'off';
            app.ContourLevelsMin.Value = 100;
            app.Timingerrorlabel_2 = uilabel(app.UIFigure);
            app.Timingerrorlabel_2.Enable = 'off';
            app.Timingerrorlabel_2.Visible = 'off';
            app.Timingerrorlabel_2.Text = 'min';
            app.Timingerrorlabel_3 = uilabel(app.UIFigure);
            app.Timingerrorlabel_3.Enable = 'off';
            app.Timingerrorlabel_3.Visible = 'off';
            app.Timingerrorlabel_3.Text = 'max';
            app.CompleteButton = uibutton(app.UIFigure, 'push');
            app.CompleteButton.ButtonPushedFcn = createCallbackFcn(app, @CompleteButtonPushed, true);
            app.CompleteButton.Text = 'Complete';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gdop_popup(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end