classdef startup_mode_selection_gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        WelcomeToRAPSUIFigure  matlab.ui.Figure
        GridLayout             matlab.ui.container.GridLayout
        ELINTModeLabel         matlab.ui.control.Label
        RadarModeLabel         matlab.ui.control.Label
        LaunchButton           matlab.ui.control.Button
        RadarModeImage         matlab.ui.control.Image
        radarModeButton        matlab.ui.control.StateButton
        ELINTModeImage         matlab.ui.control.Image
        elintModeButton        matlab.ui.control.StateButton
        InstallMapsButton      matlab.ui.control.Button
        Status                 matlab.ui.control.Label
        CAPSLabel              matlab.ui.control.Label
        Image                  matlab.ui.control.Image
        Label                  matlab.ui.control.Label
    end

    
    properties (Access = private)
        launch_mode=true; % default launch mode=ELINT
        base_app=[];
        map_prefix=[];
        map_levels=[];
    end
    
    methods (Access = private)

        function load_basemaps(app,info_file_path)
            % fetch information about prefix,zoom levels and path to
            % .mbtiles files used in basemaps
            load(info_file_path);
            app.map_prefix=map_info.prefix;
            files_path=map_info.files_path;
            zoom_levels=map_info.zoom_level_status;
            for zoom=1:length(zoom_levels)
                % map name, according to file name prefix
                map_name=[app.map_prefix '_' num2str(zoom-1)];
                % path of the .mbtile file for this zoom level
                map_file_path=[files_path '\' map_name '.mbtiles'];
                % did this zoom level exist when files paths were added?
                zoom_status=zoom_levels(zoom);
                % if both the file and required zoom level status exist,
                % then load call the addCustomBasemap function to load map
                if (zoom_status==1)
                    if isfile(map_file_path)
                        % try
                        addCustomBasemap(map_name,map_file_path,"Attribution",'Developed by Sensors Div NASTP','IsDeployable',true);
                        % fprintf('map_name=%s, Zoom=%d, file=%s\n',map_name,zoom,map_file_path);
                        warning ('off')
                        % catch ex
                        % map_info.zoom_level_status(zoom_index)=0; % this zoom level does not exist
                        % fprintf('error adding map %s : %s',map_name,ex.message);
                        % end
                    else
                        ex=MException('Basemap:file_not_found','The corresponding file does not exist');
                        throw(ex);
                    end
                end
            end
            % update the information about loaded maps into map handle
            app.map_prefix=app.map_prefix;
            app.map_levels=zoom_levels;
            app.InstallMapsButton.Visible="off";
            app.radarModeButton.Enable="on";
            app.elintModeButton.Enable="on";
            app.Status.Text = ' Maps loaded, select mode..';
            % app.la
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            app.WelcomeToRAPSUIFigure.Name =' Welcome to CAPS';
            app.WelcomeToRAPSUIFigure.Visible="off";
            movegui(app.WelcomeToRAPSUIFigure,'center')
            if ~isempty(varargin)
                app.base_app=varargin{1};
                % while isempty(app.base_app.map_axis)
                    % pause(0.3)
                % end
            end
            app.launch_mode=true;
            app.WelcomeToRAPSUIFigure.Visible="on";
            fig=figure('Visible','off');
            gx=geoaxes(fig);
            install_maps=false;
            try
                info_file_path=[pwd '\base_map_info'];
                load(info_file_path);
                if isempty(map_info.prefix) || isempty(map_info.zoom_level_status)
                    install_maps=true;
                    ex=MException('Invalid Info','empty map name or zoom info');
                    throw(ex);
                end
                % gx.Basemap=[map_info.prefix '_7'];
                % gx.ZoomLevel=7;
                load_basemaps(app,info_file_path)
            catch ex
                fprintf("%s --> %s \n",ex.identifier,ex.message);
                % updateText(app,a)
                install_maps=true;
            end
            if install_maps
                w=warndlg('No basemaps installed for the application. Please Specify location of Basmap Files now.','Basemaps Not Found','modal');
                uiwait(w);
                app.elintModeButton.Enable="off";
                app.radarModeButton.Enable="off";
                % app.LaunchButtonvis
                app.InstallMapsButton.Visible="on";
                app.InstallMapsButton.Enable="on";
                app.Status.Text='Waiting to install maps';
            end
            % app.Status.Text='';
        end

        % Value changed function: elintModeButton
        function elintModeButtonValueChanged(app, event)
            value = app.elintModeButton.Value;
            app.radarModeButton.Value=~value;
            app.RadarModeLabel.FontColor='w';
            app.radarModeButton.FontColor='black';
            app.ELINTModeLabel.FontColor='w';
            app.elintModeButton.FontColor='black';
            app.launch_mode=true; %elint mode
            app.LaunchButton.Enable="on";
            app.LaunchButton.Visible="on";
            app.elintModeButton.BackgroundColor=[0.87,0.96,1.00];
            app.radarModeButton.BackgroundColor=[0.94,0.94,0.94];
            app.RadarModeImage.Enable= 'off';
            app.ELINTModeImage.Enable= 'on';
        end

        % Value changed function: radarModeButton
        function radarModeButtonValueChanged(app, event)
            value = app.radarModeButton.Value;
            app.elintModeButton.Value=~value;
            app.RadarModeLabel.FontColor='white';
            app.radarModeButton.FontColor='black';
            app.ELINTModeLabel.FontColor='white';
            app.elintModeButton.FontColor='black';
            app.launch_mode=false; % radar mode
            app.LaunchButton.Enable="on";
            app.LaunchButton.Visible="on";
            app.radarModeButton.BackgroundColor=[0.87,0.96,1.00];
            app.elintModeButton.BackgroundColor=[0.94,0.94,0.94];
            app.ELINTModeImage.Enable= 'off';
            app.RadarModeImage.Enable= 'on';
        end

        % Button pushed function: LaunchButton
        function LaunchButtonPushed(app, event)
            if ~isempty(app.base_app)
                launch_settings=[];
                launch_settings.launch_mode=app.launch_mode;
                launch_settings.map_prefix=app.map_prefix;
                launch_settings.map_levels=app.map_levels;
                app.base_app.launch_mode_changed(launch_settings);
            end
            delete(app);
        end

        % Close request function: WelcomeToRAPSUIFigure
        function WelcomeToRAPSUIFigureCloseRequest(app, event)
            if ~isempty(app.base_app)
                delete(app.base_app)
            end
            delete(app)
        end

        % Button pushed function: InstallMapsButton
        function InstallMapsButtonPushed(app, event)
            map_manager=basemap_manager;
            uiwait(map_manager.UIFigure);
            info_file_path=[pwd '\base_map_info'];
            load_basemaps(app,info_file_path);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.WelcomeToRAPSUIFigure = uifigure('Visible', 'off');
            app.WelcomeToRAPSUIFigure.Name = 'Welcome To RAPS';
            app.WelcomeToRAPSUIFigure.Icon = fullfile(pathToMLAPP, 'icons', 'icon_nav.png');
            app.WelcomeToRAPSUIFigure.CloseRequestFcn = createCallbackFcn(app, @WelcomeToRAPSUIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.WelcomeToRAPSUIFigure);
            app.Label = uilabel(app.GridLayout);
            app.Label.Text = 'CAPS - Coverage Analysis and Plotting Suite lets you create line-of-sight coverage maps, conduct site surveys, generate TDoA accuracy plots and compile reports all at one place. To get started, select one of modes below';
            app.Image = uiimage(app.GridLayout);
            app.Image.ImageSource = 'PP.png';
            app.CAPSLabel = uilabel(app.GridLayout);
            app.CAPSLabel.Text = 'C A P S';
            app.Status = uilabel(app.GridLayout);
            app.Status.Text = '';
            app.InstallMapsButton = uibutton(app.GridLayout, 'push');
            app.InstallMapsButton.ButtonPushedFcn = createCallbackFcn(app, @InstallMapsButtonPushed, true);
            app.InstallMapsButton.Enable = 'off';
            app.InstallMapsButton.Visible = 'off';
            app.InstallMapsButton.Text = 'Install Maps';
            app.elintModeButton = uibutton(app.GridLayout, 'state');
            app.elintModeButton.ValueChangedFcn = createCallbackFcn(app, @elintModeButtonValueChanged, true);
            app.elintModeButton.Enable = 'off';
            app.elintModeButton.Text = 'Place multiple sites in a region of interest and perform LoS measurements and calculate overlapping regions of interest.';
            app.ELINTModeImage = uiimage(app.GridLayout);
            app.ELINTModeImage.ImageSource = 'rdnodes.png';
            app.radarModeButton = uibutton(app.GridLayout, 'state');
            app.radarModeButton.ValueChangedFcn = createCallbackFcn(app, @radarModeButtonValueChanged, true);
            app.radarModeButton.Enable = 'off';
            app.radarModeButton.Text = 'Quickly create a coverage map for a single site.';
            app.RadarModeImage = uiimage(app.GridLayout);
            app.RadarModeImage.ImageSource = 'rd.png';
            app.LaunchButton = uibutton(app.GridLayout, 'push');
            app.LaunchButton.ButtonPushedFcn = createCallbackFcn(app, @LaunchButtonPushed, true);
            app.LaunchButton.Enable = 'off';
            app.LaunchButton.Visible = 'off';
            app.LaunchButton.Text = 'Launch';
            app.RadarModeLabel = uilabel(app.GridLayout);
            app.RadarModeLabel.Text = 'Radar Mode';
            app.ELINTModeLabel = uilabel(app.GridLayout);
            app.ELINTModeLabel.Text = 'ELINT Mode';
            % Show the figure after all components are created
            app.WelcomeToRAPSUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = startup_mode_selection_gui(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.WelcomeToRAPSUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.WelcomeToRAPSUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.WelcomeToRAPSUIFigure)
        end
    end
end