classdef survey_report_description_popup < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        OKButton                  matlab.ui.control.Button
        SurveyNotesTextArea       matlab.ui.control.TextArea
        SurveyNotesTextAreaLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
       base_app_handle=[]; % handle to caller app
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            app.UIFigure.Name = 'Survey User Remarks';
            movegui(app.UIFigure,'center');
            base_app=[];
            if ~isempty(varargin)
                base_app=varargin{1};
            end
            if ~isempty(base_app)
                app.base_app_handle=base_app;
            end
            
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            if ~isempty(app)
                app.base_app_handle.report_description=app.SurveyNotesTextArea.Value;
            end
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SurveyNotesTextAreaLabel = uilabel(app.UIFigure);
            app.SurveyNotesTextAreaLabel.Text = 'Survey Notes';
            app.SurveyNotesTextArea = uitextarea(app.UIFigure);
            app.OKButton = uibutton(app.UIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = survey_report_description_popup(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end