classdef survey_selection_popup < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        LoadSurveyReportUIFigure   matlab.ui.Figure
        GridLayout                 matlab.ui.container.GridLayout
        StatusLabel                matlab.ui.control.Label
        CancelButton               matlab.ui.control.Button
        LoadButton                 matlab.ui.control.Button
        DescriptionTextArea        matlab.ui.control.TextArea
        DescriptionTextAreaLabel   matlab.ui.control.Label
        SurveyNameEditField        matlab.ui.control.EditField
        SurveyNameEditFieldLabel   matlab.ui.control.Label
        ConductedByEditField       matlab.ui.control.EditField
        ConductedByEditFieldLabel  matlab.ui.control.Label
        EndDateEditField           matlab.ui.control.EditField
        EndDateEditFieldLabel      matlab.ui.control.Label
        StartDateEditField         matlab.ui.control.EditField
        StartDateEditFieldLabel    matlab.ui.control.Label
        SavedSurveysListBox        matlab.ui.control.ListBox
        SavedSurveysListBoxLabel   matlab.ui.control.Label
    end

    
    properties (Access = private)
        value_selected=false % Description
        base_app_handle=[]
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
           % For now, we are looking for all available .mat files in the
           % 'saved sessions' folder. This approach is not perfect, if .mat
           % files for different purposes are saved in the same folder.
           % Instead, we need to keep track of each .mat file generated
           % aggainst a survey, then, use that single track-file to find all
           % other files related to saved surveys and their calculation
           % sessions.
           base_app=[];
           app_exec_mode=true;
           if~isempty(varargin)
               base_app=varargin{1};
               app_exec_mode=base_app.execution_mode;
           end
           app.LoadSurveyReportUIFigure.WindowStyle="normal";
           app.LoadSurveyReportUIFigure.Units="normalized";
           x=0.3;
           app.LoadSurveyReportUIFigure.Position=[x x 0.3333 0.3472];
           query=what('saved_sessions\');
           data_files=query.mat;
           app.SavedSurveysListBox.Items={};
           app.SavedSurveysListBox.ItemsData={};
           ind=1;
           for file=1:length(data_files)
               file_name=['saved_sessions\' data_files{file}];
               load(file_name);
               if ~isfield(session_data,'path')
                   session_data.path=[]; % initialize array for path of source file if not already populated
               end
               if(isempty(session_data.path))
                   session_data.path=file_name; % store path of this data source file if not already saved
               end
               if ~isfield(session_data,'LOS_link_sessions')
                   session_data.LOS_link_sessions=[]; % initialize array for prev calculation sessions if not already populated
               end
               if ~isfield(session_data,'execution_mode')
                   session_data.execution_mode=[]; % initialize array for prev calculation sessions if not already populated
               end

               if~isempty(session_data.execution_mode) % avoid errors with files already saved without execution mode info
                   if session_data.execution_mode==app_exec_mode
                   survey_name=session_data.survey_name;
                   app.SavedSurveysListBox.Items(ind)={survey_name};
                   app.SavedSurveysListBox.ItemsData(ind)={session_data};
                   ind=ind+1;
                   end
               end
           end
           if(isempty(app.SavedSurveysListBox.Items))
               app.StatusLabel.Text='No previously saved survey data found';
               app.LoadButton.Enable="off";
           else
               app.StatusLabel.Text='Select a survey from list and click load.';
           end
           app.value_selected=false;

           if ~isempty(base_app)
               app.base_app_handle=base_app;
           end
        end

        % Value changed function: SavedSurveysListBox
        function SavedSurveysListBoxValueChanged(app, event)
            session_data = app.SavedSurveysListBox.Value;
            app.SurveyNameEditField.Value=session_data.survey_name;
            app.StartDateEditField.Value=datestr(session_data.start_data);
            app.EndDateEditField.Value=datestr(session_data.end_date);
            app.ConductedByEditField.Value=session_data.user_name;
            if ~isempty(session_data.description)
                app.DescriptionTextArea.Value=session_data.description;
            end
            app.value_selected=true;
        end

        % Close request function: LoadSurveyReportUIFigure
        function LoadSurveyReportUIFigureCloseRequest(app, event)
            % if app.value_selected==false
            %     choice=questdlg("Do want to quit without loady any data?",'Warning','No','Yes','No');
            %     switch(choice)
            %         case 'Yes'
            %             delete(app)
            %         case 'No'
            %             return
            %     end
            % else
            %     choice=questdlg("Load selected survey data?",'Warning','No','Yes','No');
            % end
            % 
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            if app.value_selected==true
                if~isempty(app.base_app_handle)
                    app.base_app_handle.current_survey_data={app.SavedSurveysListBox.Value};
                    % LoadSurveyReportUIFigureCloseRequest(app);
                end
                app.base_app_handle.continue_calculation=true;
                delete(app)
            else
                msgbox('You have not selected any survey to load!','Warning','warn','modal');
            end
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            app.base_app_handle.continue_calculation=true;
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.LoadSurveyReportUIFigure = uifigure('Visible', 'off');
            app.LoadSurveyReportUIFigure.Name = 'Load Survey Report';
            app.LoadSurveyReportUIFigure.Icon = fullfile(pathToMLAPP, 'icons', 'open.png');
            app.LoadSurveyReportUIFigure.CloseRequestFcn = createCallbackFcn(app, @LoadSurveyReportUIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.LoadSurveyReportUIFigure);
            app.SavedSurveysListBoxLabel = uilabel(app.GridLayout);
            app.SavedSurveysListBoxLabel.Text = 'Saved Surveys';
            app.SavedSurveysListBox = uilistbox(app.GridLayout);
            app.SavedSurveysListBox.ValueChangedFcn = createCallbackFcn(app, @SavedSurveysListBoxValueChanged, true);
            app.StartDateEditFieldLabel = uilabel(app.GridLayout);
            app.StartDateEditFieldLabel.Text = 'Start Date';
            app.StartDateEditField = uieditfield(app.GridLayout, 'text');
            app.StartDateEditField.Editable = 'off';
            app.EndDateEditFieldLabel = uilabel(app.GridLayout);
            app.EndDateEditFieldLabel.Text = 'End Date';
            app.EndDateEditField = uieditfield(app.GridLayout, 'text');
            app.EndDateEditField.Editable = 'off';
            app.ConductedByEditFieldLabel = uilabel(app.GridLayout);
            app.ConductedByEditFieldLabel.Text = 'Conducted By';
            app.ConductedByEditField = uieditfield(app.GridLayout, 'text');
            app.ConductedByEditField.Editable = 'off';
            app.SurveyNameEditFieldLabel = uilabel(app.GridLayout);
            app.SurveyNameEditFieldLabel.Text = 'Survey Name';
            app.SurveyNameEditField = uieditfield(app.GridLayout, 'text');
            app.SurveyNameEditField.Editable = 'off';
            app.DescriptionTextAreaLabel = uilabel(app.GridLayout);
            app.DescriptionTextAreaLabel.Text = 'Description';
            app.DescriptionTextArea = uitextarea(app.GridLayout);
            app.DescriptionTextArea.Editable = 'off';
            app.LoadButton = uibutton(app.GridLayout, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Icon = 'icons8-download-64 (1).png';
            app.LoadButton.Text = 'Load';
            app.CancelButton = uibutton(app.GridLayout, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Icon = 'x-button.png';
            app.CancelButton.Text = 'Cancel';
            app.StatusLabel = uilabel(app.GridLayout);
            % Show the figure after all components are created
            app.LoadSurveyReportUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = survey_selection_popup(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.LoadSurveyReportUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.LoadSurveyReportUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.LoadSurveyReportUIFigure)
        end
    end
end