classdef targetHeights_popup < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SetTargetHeightsUIFigure  matlab.ui.Figure
        SetButton                 matlab.ui.control.Button
        feetLabel                 matlab.ui.control.Label
        height4                   matlab.ui.control.NumericEditField
        height5                   matlab.ui.control.NumericEditField
        height3                   matlab.ui.control.NumericEditField
        height2                   matlab.ui.control.NumericEditField
        height1                   matlab.ui.control.NumericEditField
        EnterheightsAMSLftatwhichtoevaluatethesensorLineofSightLoSLabel  matlab.ui.control.Label
        TargetHeightsLabel        matlab.ui.control.Label
        Panel                     matlab.ui.container.Panel
        Panel_2                   matlab.ui.container.Panel
    end

    
    properties (Access = private)
        base_app_handle = [] 
        h1= [];
        h2= [];
        h3= [];
        h4= [];
        h5 = [];
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            app.SetTargetHeightsUIFigure.Visible ='off';
            app.SetTargetHeightsUIFigure.Name = 'Set Target Heights';
            movegui(app.SetTargetHeightsUIFigure,'center');
 
            if ~isempty(varargin)
                app.base_app_handle = varargin{1};
            end
            
            %% If the heights have already been set, display the previously assigned values
            
            app.height1.Value = app.base_app_handle.tgtHeight1;
            app.height2.Value = app.base_app_handle.tgtHeight2;
            app.height3.Value = app.base_app_handle.tgtHeight3;
            app.height4.Value = app.base_app_handle.tgtHeight4;
            app.height5.Value = app.base_app_handle.tgtHeight5;
            [app.h1,app.h2,app.h3,app.h4,app.h5] = deal(app.base_app_handle.tgtHeight1,app.base_app_handle.tgtHeight2,app.base_app_handle.tgtHeight3,app.base_app_handle.tgtHeight4,app.base_app_handle.tgtHeight5);
            app.SetTargetHeightsUIFigure.Visible ='on';
            app.SetTargetHeightsUIFigure.WindowStyle = 'normal';
        end

        % Button pushed function: SetButton
        function SetButtonPushed(app, event)
            % Assign the set heights to the parent app properties
            app.base_app_handle.tgtHeight1 = app.height1.Value;
            app.base_app_handle.tgtHeight2 = app.height2.Value;
            app.base_app_handle.tgtHeight3 = app.height3.Value;
            app.base_app_handle.tgtHeight4 = app.height4.Value;
            app.base_app_handle.tgtHeight5 = app.height5.Value;
            
            if any([app.height1.Value app.height2.Value app.height3.Value app.height4.Value app.height5.Value] ~= [app.h1,app.h2,app.h3,app.h4,app.h5])
                txt = sprintf('Target heights updated (ft)\nHeight 1: %d\nHeight 2: %d\nHeight 3: %d\nHeight 4: %d\nHeight 5: %d\n',app.height1.Value,app.height2.Value,app.height3.Value,app.height4.Value,app.height5.Value);
                updateText(app.base_app_handle,txt)
                msg = msgbox("Target heights updated","CAPS Update Manager",'warn');    
                uiwait(msg)
            end
            
            delete(app.SetTargetHeightsUIFigure);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.SetTargetHeightsUIFigure = uifigure('Visible', 'off');
            app.SetTargetHeightsUIFigure.Name = 'Set Target Heights';
            app.SetTargetHeightsUIFigure.Icon = fullfile(pathToMLAPP, 'icons', 'icon_nav.png');
            app.SetTargetHeightsUIFigure.Tag = 'heightsPopup';
            app.Panel_2 = uipanel(app.SetTargetHeightsUIFigure);
            app.Panel = uipanel(app.SetTargetHeightsUIFigure);
            app.TargetHeightsLabel = uilabel(app.SetTargetHeightsUIFigure);
            app.TargetHeightsLabel.Text = 'Target Heights';
            app.EnterheightsAMSLftatwhichtoevaluatethesensorLineofSightLoSLabel = uilabel(app.SetTargetHeightsUIFigure);
            app.EnterheightsAMSLftatwhichtoevaluatethesensorLineofSightLoSLabel.Text = {'Enter heights (AMSL, ft) at which to evaluate the sensor'; 'Line-of-Sight (LoS)'};
            app.height1 = uieditfield(app.SetTargetHeightsUIFigure, 'numeric');
            app.height1.Limits = [0 100000];
            app.height1.ValueDisplayFormat = '%0.1f';
            app.height1.Value = 1000;
            app.height2 = uieditfield(app.SetTargetHeightsUIFigure, 'numeric');
            app.height2.Limits = [0 100000];
            app.height2.ValueDisplayFormat = '%0.1f';
            app.height2.Value = 5000;
            app.height3 = uieditfield(app.SetTargetHeightsUIFigure, 'numeric');
            app.height3.Limits = [0 100000];
            app.height3.ValueDisplayFormat = '%0.1f';
            app.height3.Value = 10000;
            app.height5 = uieditfield(app.SetTargetHeightsUIFigure, 'numeric');
            app.height5.Limits = [0 100000];
            app.height5.ValueDisplayFormat = '%0.1f';
            app.height5.Value = 30000;
            app.height4 = uieditfield(app.SetTargetHeightsUIFigure, 'numeric');
            app.height4.Limits = [0 100000];
            app.height4.ValueDisplayFormat = '%0.1f';
            app.height4.Value = 15000;
            app.feetLabel = uilabel(app.SetTargetHeightsUIFigure);
            app.feetLabel.Text = 'feet';
            app.SetButton = uibutton(app.SetTargetHeightsUIFigure, 'push');
            app.SetButton.ButtonPushedFcn = createCallbackFcn(app, @SetButtonPushed, true);
            app.SetButton.Text = 'Set';
            % Show the figure after all components are created
            app.SetTargetHeightsUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = targetHeights_popup(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SetTargetHeightsUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SetTargetHeightsUIFigure)
        end
    end
end